classdef Calculo_Contraste_MG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ContrastCheckBox        matlab.ui.control.CheckBox
        ED7_Delta_Cinter        matlab.ui.control.EditField
        ED6_Delta_Cin           matlab.ui.control.EditField
        DeltaC_interLabel       matlab.ui.control.Label
        DeltaC_inLabel          matlab.ui.control.Label
        BinningCheckBox         matlab.ui.control.CheckBox
        ExitButton              matlab.ui.control.Button
        ED5_Area_Relativa       matlab.ui.control.EditField
        ED4_Long_Relativa       matlab.ui.control.EditField
        ED3_Cinter_Gland        matlab.ui.control.EditField
        ED2_Cin_Gland           matlab.ui.control.EditField
        RelativeareaLabel       matlab.ui.control.Label
        RelativelengthLabel     matlab.ui.control.Label
        CinterglandLabel        matlab.ui.control.Label
        CinglandLabel           matlab.ui.control.Label
        ComputeContrastButton   matlab.ui.control.Button
        ResultsLabel            matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        LoadimageButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ndir % Nombre del directorio de imagen
        Nfich % Nombre del fichero de imagen
        Imagen % Imagen de las glandulas
        Maskup % Mascara de las glandulas
        Maskdw % Mascara de la zona interglandulas
        Maskpr % Mascara del párpado
        Cin % Contraste inglandula
        Cinter % Contraste interglandula
        StdCin % Desviacion estandard contraste interglandula
        StdCinter % Desviacion estandard contraste intglandula
        LongRelativa % Longitud relativa glándula
        AreaRelativa % Area relativa glándula
        DeltaCin % Variacion de intensidad inglandula
        DeltaCinter % Variación de intensidad interglandula
        StdDeltaCin % Desviacion estandard variacion de intensidad inglandula
        StdDeltaCinter % Desviacion estandard variación de intensidad interglandula
    end
    
    methods (Access = private)
        % Calcula Contraste función para calcular el contraste
        % de las GM
        function CalculaContraste(app)
            % CALCULACONTRASTE Función para realizar el calculo del
            % contrste de las glándulas de Meibomio
           
            % Lee la imagen
            %I = app.Imagen;
            I = imread([app.Ndir app.Nfich]);

            % JAGP flag for selecting manual (1) or automatic (0) distances inter and
            % intragland
            FMAN = 1;

            % Flag for figure selection
            FLAG_FIG = false;

            % JAGP Converts from true color to gray image
            if numel(size(I))==3
                I = rgb2gray(I);
            end

            I = im2double(I); % JAGP: Convierte a doble precisión escalada entre 0 y 1

            % Low-pass filter (equivalent to binning) if binning checkbox
            % is selected
            FLAG_BIN = app.BinningCheckBox.Value;
            if(FLAG_BIN)
                w = fspecial('average',3);
                app.Imagen = imfilter(I,w,'replicate','same');
                I = app.Imagen;
            end

            % Contrast enhancement
            if(app.ContrastCheckBox.Value)
                app.Imagen = adapthisteq(I);
                I = app.Imagen;
            end

            %%  2) Mask selection and glare removal

            % Creates the mask
            h = figure;
            warning off;
            Mask =roipoly(I);
            close(h);
            warning on;

            % Progress dialog bar
            %fig = app.UIFigure;
            hbar = uiprogressdlg(app.UIFigure,'Title','Espera por favor',...
                'Message','Preprocesando imagen');
            pause(.5)

            % Removing glares
            Bth = 1-(max(I(Mask(:)))-mean(I(Mask(:))))/7;
            MaskB = I>=Bth;
            se1 = strel('disk',6); % Coarse structuring element
            se2 = strel('disk',3); % Fine structuring element
            MaskB = imclose(MaskB,se1); % Closing for best homogeneity
            MaskB = imdilate(MaskB,se1); % Mask dilation for better
            Mask = Mask&not(MaskB); % Union of original Mask and glare Mask

            %% Mask detection

            % Computing Mask and Background using Gatos' algorithm
            [MaskG,Bkgr] = app.imbinGatos(40);

            %%%%% OLD CODE Remove in the Future
            % % Adjust image contrast using an adaptative method
            % If = adapthisteq(I);
            % If = imfilter(If,ones(5)/25,'replicate','same');
            %
            % % Remove background using a top-hat transformation
            % se = strel('disk',20);
            % If = imadjust(imtophat(If,se));

            % Get the image coordinates
            tam = size(I);
            xr = 1:tam(2); yr = 1:tam(1); % Position vectors
            [xr,yr] = meshgrid(xr,yr);

            % %%%%%%%%%% OLD CODE Remove in future
            % %% Background evaluation
            % Bkgr = imfilter(I,fspecial('average',40),'replicate');
            %
            %
            %% CONTRAST IN-GLAND
            % Actualizando barra de progreso
            hbar.Value = .33;
            hbar.Message = 'Calculando contraste Cin';
            pause(0.5);

            %
            % % Compute image with IO if is above the background and background otherwise
            % Maskup = (I>Bkgr)&imerode(Mask,se1);
            % Maskup = imopen(Maskup,se2); % Operacion de apertura para suavizar los bordes
            % %%%%%%%%%%%%%%%%%%%%%%
            %th = graythresh(If(Mask)); % Recupera el threshold solo dentro de la máscara
            %Maskup = Mask&(imbinarize(If,th)); % Crea la máscara maskup
            %Iup = Maskup.*(I - Bkgr);
            Maskup1 = Mask&MaskG; % Binarización para obtener la máscara Maskup
            Iup = Maskup1.*I;

            % Compute local maxima and minima in gland region over background and
            % matrices. Imax and Imin are masks, imin and imax indexes for the local
            % extrema
            Imax = imregionalmax(Iup); Imax = Imax & Mask;
            imax = find(Imax);
            Imin = imregionalmin(Iup); Imin = Imin & Mask;
            imin = find(Imin & Iup>0);
            % JAGP Reduce el número de puntos a un máximo de 200
            Npuntos = 400;
            if(length(imax)>Npuntos)
                imax = imax(1:floor(length(imax)/Npuntos):end);
            end
            if(length(imin)>Npuntos)
                imin = imin(1:floor(length(imin)/Npuntos):end);
            end

            Nmax = length(imax);
            Nmin = length(imin);
            % Prepare counter for all the possible contrast pairs (max-min) and
            % allocate memory
            count = 1;
            Cin1 = zeros(Nmax*Nmin,1);
            DeltaCin1 = zeros(Nmax*Nmin,1);

            % JAGP: Evaluate the maximum intergland distance
            % se = strel('disk',1);
            % MaskL = imopen(Iup,se);
            % statsMaskL = regionprops(Maskup,'Centroid','MajorAxisLength','MinorAxisLength','Area'); % Get the minor axis lenght of the glands
            MaskL = bwlabel(Maskup1);
            statsMaskL = regionprops(Maskup1,'Centroid','MajorAxisLength','MinorAxisLength','Area'); % Get the minor axis lenght of the glands
            vgWidth = cat(1,statsMaskL.MinorAxisLength);
            vgLength = cat(1,statsMaskL.MajorAxisLength);
            vgArea = cat(1,statsMaskL.Area);
            igdist = mean(vgLength(vgWidth>5)); % Get the maximum intragland distance
            vgCent = cat(1,statsMaskL.Centroid);
            vgCent = sort(vgCent,1);
            dx = diff(vgCent(:,1)); % Horizontal distances between consecutive glands
            gldist =  mean(dx(dx>10));

            % Compute the mean relative gland area and length
            statsMask = regionprops(Mask,'Area','BoundingBox');
            MaskArea = cat(1,statsMask.Area);
            MaskBox = cat(1,statsMask.BoundingBox);
            MaskLength = MaskBox(4);
            RLG1 = mean(vgLength)/MaskLength; % Computes the mean lenght of the glands and divides by the length of the Mask
            RGA1 = sum(vgArea)/max(MaskArea); % Computes the relative area of the glands with respect to that of the Mask

            % Figure with the high contrast image
            %f3 = figure(3);
            %set(f3,'Color',[1,1,1],'WindowStyle','docked')
            if(FLAG_FIG)
                figure;
                hold off
                %Ihc = Mask.*(I-Bkgr); Ihc = imadjust(Ihc,[0 max(Ihc(:))],[0 1]);
                Ihc = adapthisteq(Mask.*I);
                imagesc(Ihc); axis equal tight; colormap gray; colorbar
                title('High contrast image')
            end

            % Figure of in-gland selection extrema
            %f4 = figure(4);
            %set(f4,'Color',[1,1,1],'WindowStyle','docked')
            if(FLAG_FIG)
                h2 = figure;
                hold off
                %imagesc(I0r - Bkgr); axis equal tight; colormap gray; colorbar
                imagesc(Ihc); axis equal tight; colormap gray; colorbar
                hold on
                %plot(xr(imin),yr(imin),'yd'); %,'MarkerSize',10,'LineWidth',1.5)
                %plot(xr(imax),yr(imax),'ro'); %,'MarkerSize',10,'LineWidth',1.5)
                xlabel('X (Pixels)','FontSize',14)
                ylabel('Y (Pixels)','FontSize',14)
                title('Pairs for Cin','FontSize',18)
            end

            % Pair selection and contrast computation
            % Maximum distance (pixel) for pair validation

            if(FMAN)
                dmax = 20;
            else
                dmax = igdist;
            end

            for n = 1:Nmax
                for m = 1:Nmin
                    maxx = I(imax(n));
                    minn = I(imin(m));
                    d = sqrt((xr(imax(n))-xr(imin(m)))^2 + (yr(imax(n))-yr(imin(m)))^2);
                    % JAGP Code for checking that both points belongs to the same gland
                    vimin = floor(yr(imin(m))); vjmin = floor(xr(imin(m)));
                    if(vimin==0)
                        vimin = vimin+1;
                    end
                    if(vjmin==0)
                        vjmin = vjmin +1;
                    end
                    minLabel = MaskL(vimin,vjmin);
                    vimax = floor(yr(imax(n))); vjmax = floor(xr(imax(n)));
                    if(vimax==0)
                        vimax = vimax+1;
                    end
                    if(vjmax==0)
                        vjmax = vjmax +1;
                    end
                    maxLabel = MaskL(vimax,vjmax);
                    % If maxLabel == minLabel both points are located within the same
                    % gland
                    if (maxx > minn && d < dmax) && (minLabel == maxLabel)
                        Cin1(count) = (maxx - minn)/(maxx + minn);
                        DeltaCin1(count) = (maxx - minn);
                        % Plot segment for each validated pair
                        if(FLAG_FIG)
                            get(h2);
                            plot([xr(imax(n)),xr(imin(m))],[yr(imax(n)),yr(imin(m))],'c','LineWidth',1.5);
                            plot(xr(imin(m)),yr(imin(m)),'yd','MarkerSize',8,'LineWidth',1.5);
                            plot(xr(imax(n)),yr(imax(n)),'ro','MarkerSize',8,'LineWidth',1.5);
                        end
                        count = count + 1;
                    end
                end
            end
            % Delete non-used entries in Cin
            Cin1(count:end) = [];
            DeltaCin1(count:end) = [];
            avgCin = mean(Cin1);
            avgDeltaCin = mean(DeltaCin1);
            stdDeltaCin = std(DeltaCin1);

            %% CONTRAST INTER-GLAND

            % Actualizando barra de progreso
            hbar.Value = .66;
            hbar.Message = 'Calculando contraste Cinter';
            pause(0.5);

            % Compute image with IO if it is below the background and the background
            % otherwise
            %Maskdw = (I<Bkgr)&Mask;
            %Idw = Maskdw.*(I - Bkgr);
            %Maskdw = Mask&not(imbinarize(If,th));
            Maskdw1 = Mask&not(MaskG);
            Idw = Maskdw1.*I;

            % Compute local minima in the generated surface
            Imin = imregionalmin(Idw);
            imin = find(Imin & Maskdw1); %Idw<0);
            Nmin = length(imin);
            if(Nmin>Npuntos)
                imin = imin(1:floor(Nmin/Npuntos):end);
                Nmin = length(imin);
            end

            % Memory allocation for Cinter and counter initialitation
            Cinter1 = zeros(Nmax*Nmin,1);
            DeltaCinter1 = zeros(Nmax*Nmin,1);
            count = 1;

            % Figure with in-gland maxima and intergland minima
            %f5 = figure(5);
            %set(f5,'Color',[1,1,1],'WindowStyle','docked')
            if(FLAG_FIG)
                figure;
                hold off
                Ic = I.*Mask; Ic = imadjust(Ic,[0 max(Ic(:))],[0 1]);
                imagesc(Ic); axis equal tight; colormap gray; colorbar
                hold on
                %plot(xr(imin),yr(imin),'yd'); %,'MarkerSize',8,'LineWidth',1.5)
                %plot(xr(imax),yr(imax),'ro'); %,'MarkerSize',8,'LineWidth',1.5)
                xlabel('X (Pixels)','FontSize',14)
                ylabel('Y (Pixels)','FontSize',14)
                title('Pairs for Cinter','FontSize',18)
            end

            % Pair selection and contrast computation
            % Maximum distance (pixel) for pair validation
            if(FMAN)
                dmax = 30;
            else
                dmax = gldist;
            end

            for n = 1:Nmax
                for m = 1:Nmin
                    maxx = I(imax(n));
                    minn = I(imin(m));
                    d = sqrt((xr(imax(n))-xr(imin(m)))^2 + (yr(imax(n))-yr(imin(m)))^2);
                    aux = (maxx - minn)/(maxx + minn);
                    aux1 = (maxx - minn);
                    % JAGP Checks that the minimum does not belong to any gland
                    vimin = floor(yr(imin(m))); vjmin = floor(xr(imin(m)));
                    if(vimin==0)
                        vimin = vimin+1;
                    end
                    if(vjmin==0)
                        vjmin = vjmin +1;
                    end
                    minLabel = MaskL(vimin,vjmin);
                    % If the point is located in a zone between glands, the label is
                    % always zero (see bwlabel help, 0 is always the background)
                    % Condition based on value, distance and contrast bigger than
                    % avg(Cin)
                    if (maxx > minn && d < dmax && aux > avgCin)&&(minLabel==0)
                        Cinter1(count) = aux;
                        DeltaCinter1(count) = aux1;
                        if(FLAG_FIG)
                            plot([xr(imax(n)),xr(imin(m))],[yr(imax(n)),yr(imin(m))],'c','LineWidth',1.5);
                            plot(xr(imin(m)),yr(imin(m)),'yd','MarkerSize',8,'LineWidth',1.5);
                            plot(xr(imax(n)),yr(imax(n)),'ro','MarkerSize',8,'LineWidth',1.5);
                        end
                        count = count + 1;
                    end
                end
            end
            % Delete non-used entries in Cinter
            Cinter1(count:end) = [];
            DeltaCinter1(count:end) = [];
            avgCinter = mean(Cinter1);
            avgDeltaCinter = mean(DeltaCinter1);
            stdDeltaCinter = std(DeltaCinter1);
            
            %% PLOT CONTRAST
            %f6 = figure(6);
            %set(f6,'Color',[1,1,1],'WindowStyle','docked')
            Nin = length(Cin1);
            Ninter = length(Cinter1);
            xin = 1:Nin;
            xinter = 1:Ninter;
            % Mean and standard deviation: in-gland contrast
            muCin = mean(Cin1); stdCin = std(Cin1);
            Cin11 = muCin+stdCin;
            Cin12 = muCin-stdCin;
            % Mean and standard deviation: inter-glnad contrast
            muCinter = mean(Cinter1); stdCinter = std(Cinter1);
            Cinter11 = muCinter+stdCinter;
            Cinter12 = muCinter-stdCinter;

            if(FLAG_FIG)
                figure
                plot(xin,100*Cin1,'b.',[1,Nin],mean(100*Cin1)*[1 1],'b',...
                    xinter,100*Cinter1,'r.',[1,Ninter],mean(100*Cinter1)*[1 1],'r','MarkerSize',12,'LineWidth',2);

                hold on
                plot( [1 Nin],100*[Cin11 Cin11],'b--',[1 Nin],100*[Cin12 Cin12],'b--',...
                    [1 Ninter],100*[Cinter11 Cinter11],'r--',[1 Ninter],100*[Cinter12 Cinter12],'r--','MarkerSize',12,'LineWidth',2);
                legend('Cin','mean(Cin)','Cinter','mean(Cinter)')
                h = gca;
                set(h,'FontSize',14)
                set(h,'YLim',[0 25]);
                xlabel('Pair number','FontSize',18)
                ylabel('Contrast (%)','FontSize',18)
                title('Measured contrast','FontSize',18)
            end

            if(FLAG_FIG)
                h6 = figure;
                Cbox = [100*Cin1(:); 100*Cinter1(:)];
                g1 = repmat({'Cin'},length(Cin1),1);
                g2 = repmat({'Cinter'},length(Cinter1),1);
                TipoCont = [g1; g2];
                boxplot(Cbox,TipoCont);
                xlabel('Type','FontSize',14);
                ylabel('Contrast (%)','FontSize',14);
                h = gca; set(h,'FontSize',12);
            end

            % Actualizando barra de progreso
            hbar.Value = 1;
            hbar.Message = 'Calculo finalizado';
            pause(0.5);
            close(hbar);
            %close(fig);

            % Actualizando las propiedades de la app
            app.AreaRelativa = RGA1;
            app.LongRelativa = RLG1;
            app.Cin = avgCin;
            app.Cinter = avgCinter;
            app.StdCin = stdCin;
            app.StdCinter = stdCinter;
            app.Maskup = Maskup1;
            app.Maskdw = Maskdw1;
            app.Maskpr = Mask;
            app.DeltaCin = 256*avgDeltaCin;
            app.StdDeltaCin = 256*stdDeltaCin;
            app.DeltaCinter = 256*avgDeltaCinter;
            app.StdDeltaCinter = 256*stdDeltaCinter;

            % Devuelve el valor de rp añadiendo a la matriz rpin
            %rp = [rpin; rp];

            % Cierra la figura f1
            %close(f1);

        end

        % Función auxiliar de binarización según la técnica de
        % Gatos' et al
        function [B,Bkg] = imbinGatos(app,w)
            % IMBINGATOS Función para binarizar la imagen de las
            % glándulas de Meibomio usando el algoritmo de Gatos et al
            
            % Comprueba si hay una imagen cargada en la memoria
            if(isempty(app.Imagen))
                % Si no hay imagen el algoritmo da error
                errordlg('No hay imagen cargada')
                B = [];
                Bkg = [];
                return;
            end

            % Lee la imagen
            Is = app.Imagen;

            %%%% Aplicacion del algoritmo de Gatos

            % Checks whether the input image is rgb
            if(numel(size(Is))==3)
                Is = rgb2gray(Is);
            end
            Is = im2double(Is); % Convert to double precision

            % Preprocessing: Wiener filter
            % M = imfilter(Is,ones(w)/w/w,'replicate','same'); % Local mean
            % fun = @(x) var(x(:));
            % S1 = nlfilter(Is,[3 3],fun); % Variance
            % V = imfilter(S1,ones(3)/9,'replicate','same'); % Mean of variances
            % I = M + ((S1-V).*(Is-M))./S1; % Wiener filter according to Gatos et al
            % NOTE: Currently this implementation of Wiener's filter does not work.
            % Pending further study we use for now a standard local adaptive
            % equalization filter
            I = adapthisteq(Is);
            % If Binning is selected apply a low pass filter
            if(app.BinningCheckBox.Value)
                w1 = fspecial('gaussian',5,3);
                I = imfilter(I,w1,'replicate','same');
            end

            % Estimation of foreground regions using Niblack's algorithm
            % NOTE: Gatos et al propose Sauvolas algorithm for this step but I cannot
            % get it to work. Provisionally, I am using Niblack's instead
            S = imbinNiblack(I,w);

            % Determine the background term
            V1 = (1-S); % First function
            V2 = I.*V1; % Second function
            Num = imfilter(V2,ones(w),'replicate','same');
            Den = imfilter(V1,ones(w),'replicate','same');
            Bkg = I.*not(S) + (Num./(Den+eps)).*S; % Background term

            % Determine the average distance between foreground and background
            delta = sum(I(:)-Bkg(:))/sum(S(:));

            % Determine the values of logistical function to weighten distance
            % delta
            p1 = 0.5; p2 = 0.8; q=0.6; % Values from Gatos' paper
            b = sum(Bkg(:).*(1-S(:)))/sum(1-S(:));
            log_fun = (1-p2)./(1+exp(-4*Bkg/(b*(1-p1))))+p2; % Logistical function

            % Set threshold
            T = q*delta*log_fun;

            % Image binarization
            B = (I-Bkg)>T;

            % Remove mask noise if BinningCheckBox is on
            if(app.BinningCheckBox.Value)
                se = strel('Disk',1);
                B = imopen(B,se);
            end

        end

        
        % Función auxiliar para representar la máscara sobre la
        % imagen de las glándulas
        function MaskOverlay(app,Mask_col)
            % Set chromatic channels
            if(nargin==1)
                Mask_col = [0.9 0 1];
            end
            % Get the image and mask
            I = im2double(app.Imagen);
            if(numel(size(I))==3)
                I = rgb2gray(I);
            end
            %Iv = I(:);
            %maxI = max(Iv(app.Maskpr(:)>0));
            %minI = min(Iv(app.Maskpr(:)>0));
            %I = imadjust(I,[minI maxI],[0 1],0.9);
            I = adapthisteq(I,'clipLimit',0.02);
            I = cat(3,I,I,I);
            Mask1 = app.Maskup;
            se = strel('disk',2);
            Mask = imdilate(Mask1,se)&not(imerode(Mask1,se));
            Mask = double(Mask);
            for k=1:3
            I(:,:,k) = Mask_col(k)*I(:,:,k).*Mask +...
                I(:,:,k).*not(Mask);
            end
            % Represents the overlayed image
            imshow(I,'Parent',app.UIAxes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            
            % 1. Selecciona el directorio
            %[Nfich1,Ndir1] = uigetfile('*.*','Selecciona imagen para abrir');
            [Nfich1,Ndir1] = uigetfile('*.*','Select an image to open');
            % 2. Carga la imagen
            try
                I = imread([Ndir1 Nfich1]);
            catch se
                %errordlg(se.message,'No se puede abrir la imagen');
                errordlg(se.message,'Cannot open the image');
                return;
            end
            
            % 3. Muestra la imagen y el fichero en los sitios correspondientes
            imshow(I,'Parent',app.UIAxes);
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            app.UIAxes.Title.String = 'Gland image';
            app.FileNameEditField.Value = Nfich1;
            
            % 4. Fija el valor de las propiedes de la App
            app.Ndir = Ndir1;
            app.Nfich = Nfich1;
            app.Imagen = I;
        end

        % Button pushed function: ComputeContrastButton
        function ComputeContrastButtonPushed(app, event)
            
            % 1. Comprueba si hay una imagen cargada
            if(isempty(app.Imagen))
                %errordlg(['Hay que cargar una imagen' ...
                %    ' para calcular el contraste'],'No hay imagen cargada');
                errordlg(['Must load an image' ...
                    ' to compute contrast'],'No image loaded');
                return;
            end

            % 2. Calcula el contraste
            try
                app.CalculaContraste; % Calcula el contraste
                app.MaskOverlay; % Representa la máscara de las glándulas
            catch se
                %errordlg(se.message,'Error al calcular el contraste');
                errordlg(se.message,'Error when computing contrast');
            end

            % 3. Actualiza los parámetros del contraste
            app.ED2_Cin_Gland.Value = [num2str(100*app.Cin,3) ' ' char(177) ' ' num2str(100*app.StdCin,3)];
            app.ED3_Cinter_Gland.Value = [num2str(100*app.Cinter,3) ' ' char(177) ' ' num2str(100*app.StdCinter,3)];
            app.ED4_Long_Relativa.Value = num2str(100*app.LongRelativa,3);
            app.ED5_Area_Relativa.Value = num2str(100*app.AreaRelativa,3);
            app.ED6_Delta_Cin.Value = [num2str(app.DeltaCin,3 ) ' ' char(177) ' ' num2str(app.StdDeltaCin,3)];
            app.ED7_Delta_Cinter.Value = [num2str(app.DeltaCinter,3) ' ' char(177) ' ' num2str(app.StdDeltaCinter,3)];
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Close the app
            close(app.UIFigure);
            return;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.ComputeContrastButton = uibutton(app.UIFigure, 'push');
            app.ComputeContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeContrastButtonPushed, true);
            app.ComputeContrastButton.Text = 'Compute Contrast';
            app.CinglandLabel = uilabel(app.UIFigure);
            app.CinglandLabel.Text = 'Cin-gland';
            app.CinterglandLabel = uilabel(app.UIFigure);
            app.CinterglandLabel.Text = 'Cinter-gland';
            app.RelativelengthLabel = uilabel(app.UIFigure);
            app.RelativelengthLabel.Text = 'Relative length';
            app.RelativeareaLabel = uilabel(app.UIFigure);
            app.RelativeareaLabel.Text = 'Relative area';
            app.ED2_Cin_Gland = uieditfield(app.UIFigure, 'text');
            app.ED2_Cin_Gland.Tag = 'ET2_Cin_gland';
            app.ED3_Cinter_Gland = uieditfield(app.UIFigure, 'text');
            app.ED3_Cinter_Gland.Tag = 'ET2_Cin_gland';
            app.ED4_Long_Relativa = uieditfield(app.UIFigure, 'text');
            app.ED4_Long_Relativa.Tag = 'ET2_Cin_gland';
            app.ED5_Area_Relativa = uieditfield(app.UIFigure, 'text');
            app.ED5_Area_Relativa.Tag = 'ET2_Cin_gland';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BinningCheckBox = uicheckbox(app.UIFigure);
            app.BinningCheckBox.Tag = 'CB1_Binning';
            app.BinningCheckBox.Text = 'Binning';
            app.DeltaC_inLabel = uilabel(app.UIFigure);
            app.DeltaC_inLabel.Text = 'DeltaC_in';
            app.DeltaC_interLabel = uilabel(app.UIFigure);
            app.DeltaC_interLabel.Text = 'DeltaC_inter';
            app.ED6_Delta_Cin = uieditfield(app.UIFigure, 'text');
            app.ED6_Delta_Cin.Tag = 'ET2_Cin_gland';
            app.ED7_Delta_Cinter = uieditfield(app.UIFigure, 'text');
            app.ED7_Delta_Cinter.Tag = 'ET2_Cin_gland';
            app.ContrastCheckBox = uicheckbox(app.UIFigure);
            app.ContrastCheckBox.Text = 'Contrast';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculo_Contraste_MG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end